classdef import_MGG_txt_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImportdataUIFigure            matlab.ui.Figure
        BrowseButton                  matlab.ui.control.Button
        BrowseButton_2                matlab.ui.control.Button
        SelectSQUIDdatafiletoimportLabel  matlab.ui.control.Label
        SelectSQUIDarrayfiletoimportLabel  matlab.ui.control.Label
        CancelButton                  matlab.ui.control.Button
        OKButton                      matlab.ui.control.Button
        EditField                     matlab.ui.control.EditField
        EditField_2                   matlab.ui.control.EditField
        CalibratedataLabel            matlab.ui.control.Label
        CheckBox                      matlab.ui.control.CheckBox
        UseexistingcalibrationfactorLabel  matlab.ui.control.Label
        UsenewcalibrationfactorLabel  matlab.ui.control.Label
        exist_calib_factor_box        matlab.ui.control.CheckBox
        new_calib_factor_filepath     matlab.ui.control.EditField
        browse_for_new_calib_fact     matlab.ui.control.Button
    end

    
    properties (Access = public)
        squidarray_filepath = 0;
        squiddata_filepath = 0; % Description
        needCalib = 0;
        existCalibfile = 0;
        newCalibSelected = 0;
        newCalib_filepath = 0;
        big_old_pos = [724, 400,474,259];
        calib_old_pos = [410,-20,32,26];
 
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function ButtonPushed(app, event)
            [filename_array,path_array]=uigetfile({'*.*'}, ...
                "Select SQUID array file to import");
            if strcmp(string(filename_array), '0') || strcmp(string(path_array), '0')
                app.EditField.Value = '';
                app.squidarray_filepath = 0;
                drawnow;
                figure(app.ImportdataUIFigure)
                return
            end
            app.squidarray_filepath=fullfile(path_array,filename_array);
            app.EditField.Value = app.squidarray_filepath;
            drawnow;
            figure(app.ImportdataUIFigure)

        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [filename_data,path_data]=uigetfile({'*.*'}, ...
                "Select SQUID data file to import");
            if strcmp(string(filename_data), '0') || strcmp(string(path_data), '0')
                app.EditField_2.Value = '';
                app.squiddata_filepath = 0;
                drawnow;
                figure(app.ImportdataUIFigure)
                return
            end
            app.squiddata_filepath=fullfile(path_data,filename_data);
            app.EditField_2.Value = app.squiddata_filepath;
            drawnow;
            figure(app.ImportdataUIFigure)
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if app.squidarray_filepath == 0 

                uialert(app.ImportdataUIFigure,'No SQUID array file has been selected','Error');
                drawnow;
                figure(app.ImportdataUIFigure)
            elseif app.squiddata_filepath == 0
                uialert(app.ImportdataUIFigure,'No SQUID data file has been selected','Error');
                drawnow;
                figure(app.ImportdataUIFigure)

            elseif app.needCalib == 1 && app.existCalibfile == 0 && app.newCalibSelected == 0
                CheckBoxValueChanged(app, 'calib_error')
                 
            else
                squidarray_filepath = app.squidarray_filepath;
                squiddata_filepath = app.squiddata_filepath;
                calib_setting = app.needCalib;
                existing_calib = app.existCalibfile;
                
                if app.newCalibSelected == 1
                    calib_filepath = app.newCalib_filepath;
                else
                    if exist('filepaths.mat', 'file')
                        load filepaths calib_filepath
                    else 
                        calib_filepath = '';
                    end
                end
                
                save filepaths.mat squidarray_filepath squiddata_filepath calib_setting calib_filepath
                % assignin('caller','squidarray_filepath',app.squidarray_filepath);
                % assignin('caller','squiddata_filepath',app.squiddata_filepath);
                delete(app.ImportdataUIFigure)
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed2(app, event)
            delete(app.ImportdataUIFigure)
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value == 1
                
                app.needCalib = 1;
                app.UseexistingcalibrationfactorLabel.Visible = 'on';
                app.exist_calib_factor_box.Visible = 'on';
                app.UsenewcalibrationfactorLabel.Visible = 'on';
                app.browse_for_new_calib_fact.Visible = 'on';
                app.new_calib_factor_filepath.Visible = 'on';
                
                % Position of components
                big_pos = app.big_old_pos;
                calib_browse_pos = app.calib_old_pos;
                                                              
                % Rescaling figure
                app.ImportdataUIFigure.Position = [big_pos(1), big_pos(2), big_pos(3), big_pos(4) + calib_browse_pos(4)*3];
                pause(0.05)
              
                app.OKButton.Position = [342,17,100,22];
                app.CancelButton.Position = [215,17,100,22];
               
            else 
                app.needCalib = 0;
                app.UseexistingcalibrationfactorLabel.Visible = 'off';
                app.exist_calib_factor_box.Visible = 'off';
                app.UsenewcalibrationfactorLabel.Visible = 'off';
                app.browse_for_new_calib_fact.Visible = 'off';
                app.new_calib_factor_filepath.Visible = 'off';
                             
                app.ImportdataUIFigure.Position = [724, 400,474,259];
                app.SelectSQUIDarrayfiletoimportLabel.Position = [29,212,265,26];
                app.SelectSQUIDdatafiletoimportLabel.Position = [30,127,265,26];
                app.BrowseButton.Position = [30,174,100,27];
                app.BrowseButton_2.Position = [29,92,100,27];
                app.EditField.Position = [146,174,296,27];
                app.EditField_2.Position = [146,92,296,27];
                app.CalibratedataLabel.Position = [30,48,78,27];
                app.CheckBox.Position = [215,48,14,22];
                app.OKButton.Position = [342,17,100,22];
                app.CancelButton.Position = [215,17,100,22];
                app.UseexistingcalibrationfactorLabel.Position = [30,13,175,26];
                app.UsenewcalibrationfactorLabel.Position = [30,-20,147,26];
                app.exist_calib_factor_box.Position = [215,15,14,22];
                app.new_calib_factor_filepath.Position = [215,-20,183,26];
                app.browse_for_new_calib_fact.Position = [410,-20,32,26];
            end 
            
            if strcmp('calib_error', event)
                uialert(app.ImportdataUIFigure,'Please select a calibration factor setting','Error');
                drawnow;
                figure(app.ImportdataUIFigure)
            end
        end

        % Value changed function: exist_calib_factor_box
        function exist_calib_factor_boxValueChanged(app, event)
            % So this should output something to matlab that tells it to
            % use the same .mat file.
            value = app.exist_calib_factor_box.Value;
            
            if value == 1 && ~strcmp('new_calib_selected', event)
                % Update value so pressing ok button knows to save this as a
                % .mat file. Don't want to do this immediately to avoid
                % creating clutter.
                app.existCalibfile = 1;
                app.newCalibSelected = 0;
                browse_for_new_calib_factPushed2(app, 'existing_calib_factor')
            else
                app.existCalibfile = 0;
            end
            
            if strcmp('new_calib_selected', event)
                app.exist_calib_factor_box.Value = 0;
            end
        end

        % Button pushed function: browse_for_new_calib_fact
        function browse_for_new_calib_factPushed2(app, event)
            
            if ~strcmp('existing_calib_factor', event)
                % Update value so pressing ok button knows to save this as a
                % .mat file. Don't want to do this immediately to avoid
                % creating clutter.
                [filename_calib,path_calib]=uigetfile({'*.*'}, ...
                    "Import calibration file");
                app.newCalibSelected = 1;
                
                if strcmp(string(filename_calib), '0') || strcmp(string(path_calib), '0')
                    app.new_calib_factor_filepath.Value = '';
                    app.newCalibSelected = 0;
                    drawnow;
                    figure(app.ImportdataUIFigure)
                    return
                end
                
                app.newCalib_filepath = fullfile(path_calib,filename_calib);
                app.new_calib_factor_filepath.Value = app.newCalib_filepath;
                drawnow;
                figure(app.ImportdataUIFigure)
                exist_calib_factor_boxValueChanged(app, 'new_calib_selected')
                
            else
                app.newCalibSelected = 0;
            end
            
            if strcmp('existing_calib_factor', event)
                app.new_calib_factor_filepath.Value = '';
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImportdataUIFigure = uifigure('Visible', 'off');
            app.ImportdataUIFigure.Name = 'Import data';
            app.BrowseButton = uibutton(app.ImportdataUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.BrowseButton_2 = uibutton(app.ImportdataUIFigure, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.SelectSQUIDdatafiletoimportLabel = uilabel(app.ImportdataUIFigure);
            app.SelectSQUIDdatafiletoimportLabel.Text = 'Select SQUID data file to import';
            app.SelectSQUIDarrayfiletoimportLabel = uilabel(app.ImportdataUIFigure);
            app.SelectSQUIDarrayfiletoimportLabel.Text = 'Select SQUID array file to import';
            app.CancelButton = uibutton(app.ImportdataUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed2, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.ImportdataUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.EditField = uieditfield(app.ImportdataUIFigure, 'text');
            app.EditField_2 = uieditfield(app.ImportdataUIFigure, 'text');
            app.CalibratedataLabel = uilabel(app.ImportdataUIFigure);
            app.CalibratedataLabel.Text = 'Calibrate data';
            app.CheckBox = uicheckbox(app.ImportdataUIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.UseexistingcalibrationfactorLabel = uilabel(app.ImportdataUIFigure);
            app.UseexistingcalibrationfactorLabel.Visible = 'off';
            app.UseexistingcalibrationfactorLabel.Text = 'Use existing calibration factor';
            app.UsenewcalibrationfactorLabel = uilabel(app.ImportdataUIFigure);
            app.UsenewcalibrationfactorLabel.Visible = 'off';
            app.UsenewcalibrationfactorLabel.Text = 'Use new calibration factor';
            app.exist_calib_factor_box = uicheckbox(app.ImportdataUIFigure);
            app.exist_calib_factor_box.ValueChangedFcn = createCallbackFcn(app, @exist_calib_factor_boxValueChanged, true);
            app.exist_calib_factor_box.Visible = 'off';
            app.exist_calib_factor_box.Text = '';
            app.new_calib_factor_filepath = uieditfield(app.ImportdataUIFigure, 'text');
            app.new_calib_factor_filepath.Visible = 'off';
            app.browse_for_new_calib_fact = uibutton(app.ImportdataUIFigure, 'push');
            app.browse_for_new_calib_fact.ButtonPushedFcn = createCallbackFcn(app, @browse_for_new_calib_factPushed2, true);
            app.browse_for_new_calib_fact.Visible = 'off';
            app.browse_for_new_calib_fact.Text = {'...'; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            % Show the figure after all components are created
            app.ImportdataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = import_MGG_txt_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImportdataUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImportdataUIFigure)
        end
    end
end